classdef power_mApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectFilesButton               matlab.ui.control.Button
        StartAnalysisButton             matlab.ui.control.Button
        FilestoAnalyseListBox           matlab.ui.control.ListBox
        DeleteSelectedFileButton        matlab.ui.control.Button
        SamplingRateHzEditFieldLabel    matlab.ui.control.Label
        SamplingRateHzEditField         matlab.ui.control.NumericEditField
        WindowWidthsLabel               matlab.ui.control.Label
        WindowSizeEditField             matlab.ui.control.NumericEditField
        WindowStepsLabel                matlab.ui.control.Label
        StepEditField                   matlab.ui.control.NumericEditField
        Band1_1                         matlab.ui.control.NumericEditField
        Band1_2                         matlab.ui.control.NumericEditField
        Band2_2                         matlab.ui.control.NumericEditField
        Band2EditFieldLabel             matlab.ui.control.Label
        Band2_1                         matlab.ui.control.NumericEditField
        Band3_2                         matlab.ui.control.NumericEditField
        Band3EditField_2Label           matlab.ui.control.Label
        Band3_1                         matlab.ui.control.NumericEditField
        Band4_2                         matlab.ui.control.NumericEditField
        Band4EditFieldLabel             matlab.ui.control.Label
        Band4_1                         matlab.ui.control.NumericEditField
        Band5_2                         matlab.ui.control.NumericEditField
        Band5EditFieldLabel             matlab.ui.control.Label
        Band5_1                         matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        DownsampleDataLabel             matlab.ui.control.Label
        WindowingParametersLabel        matlab.ui.control.Label
        PowerBandsHzLabel               matlab.ui.control.Label
        DomingosCastroPauloAguiarLabel  matlab.ui.control.Label
        PowerMLabel                     matlab.ui.control.Label
        appLabel                        matlab.ui.control.Label
        domingoscastroi3supptLabel      matlab.ui.control.Label
        DownsampletoHzDropDownLabel     matlab.ui.control.Label
        Downsample_DropDown             matlab.ui.control.DropDown
        Band1EditFieldLabel_2           matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        MonopolarButton                 matlab.ui.control.RadioButton
        BipolarButton                   matlab.ui.control.RadioButton
        ReferenceTypeLabel              matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Default Parameters:
        default_powerBands = [0.1     1;  % <-- Change default power bands here!
            1    10;
            10   50;
            50  250;
            0     0]; % Hz
        
        
        default_window_size = 1; % sec
        default_step = 0.5; % sec
        default_sampRate = 2048; % Hz
        downsample_fractions = [1; 2; 4; 8];
        
        % App Variables
        filenames;
        filenamesPaths;
        original_sampRate;
        down_sampRate;
        settings; % Struct with:
        %  settings.powerBands
        %  settings.window_size
        %  settings.window_step
        %  (consider unpacking this struct...)
        status;
        dataPath = pwd;
    end
    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(pwd)); % Add subfolders to path
            
            % Windowing Options
            app.settings.window_size = app.default_window_size;
            app.settings.window_step = app.default_step;
            app.WindowSizeEditField.Value = app.settings.window_size;
            app.StepEditField.Value = app.settings.window_step;
            
            % Sampling Rate options:
            app.original_sampRate = app.default_sampRate;
            app.down_sampRate = app.default_sampRate;
            app.SamplingRateHzEditField.Value = app.original_sampRate;
            app.Downsample_DropDown.Items = string(num2str(app.original_sampRate./app.downsample_fractions));
            
            % Power Band:
            app.settings.powerBands = app.default_powerBands;
            app.Band1_1.Value = app.settings.powerBands(1,1);
            app.Band1_2.Value = app.settings.powerBands(1,2);
            app.Band2_1.Value = app.settings.powerBands(2,1);
            app.Band2_2.Value = app.settings.powerBands(2,2);
            app.Band3_1.Value = app.settings.powerBands(3,1);
            app.Band3_2.Value = app.settings.powerBands(3,2);
            app.Band4_1.Value = app.settings.powerBands(4,1);
            app.Band4_2.Value = app.settings.powerBands(4,2);
            app.Band5_1.Value = app.settings.powerBands(5,1);
            app.Band5_2.Value = app.settings.powerBands(5,2);
            
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            
            filter = [app.dataPath '\*.mat'];
            full_filenames = uipickfiles('FilterSpec', filter, 'output', 'cell');
            
            if ~isempty(full_filenames)
                nNew_Files = numel(full_filenames);
                nCurrentFiles = numel(app.filenames);
                
                for file = 1:nNew_Files
                    [fPath, fName, ~] = fileparts(full_filenames{file});
                    app.filenames{file + nCurrentFiles} = fName;
                    app.filenamesPaths{file + nCurrentFiles} = fPath;
                end
                
                app.dataPath = fPath;
                app.FilestoAnalyseListBox.Items = app.filenames;
                app.DeleteSelectedFileButton.Enable = 1;
            end
            
            app.SamplingRateHzEditField.Enable = 1;
            app.Downsample_DropDown.Enable = 1;
            
        end

        % Value changed function: FilestoAnalyseListBox
        function FilestoAnalyseListBoxValueChanged(app, event)
            % DELETE THIS
        end

        % Callback function
        function ClearSelectedfileButtonValueChanged(app, event)
            % DELETE THIS
        end

        % Button pushed function: DeleteSelectedFileButton
        function DeleteSelectedFileButtonPushed(app, event)
            
            [~, Index] = ismember(app.FilestoAnalyseListBox.Value, app.filenames);
            app.filenames(Index) = [];
            app.filenamesPaths(Index) = [];
            app.FilestoAnalyseListBox.Items = app.filenames;
            
        end

        % Button pushed function: StartAnalysisButton
        function StartAnalysisButtonPushed(app, event)
            
            % Block Sampling Rate Options
            % (because the original data will be replaced subsampled data, so you won't be able to go back to the original sampling rate)
            app.SamplingRateHzEditField.Enable = 0;
            app.Downsample_DropDown.Enable = 0;
            
            if app.MonopolarButton.Value
                reference_type = 'monopolar';
            elseif app.BipolarButton.Value
                reference_type = 'bipolar';
            end
            
            % Clean Bands:
            bands_idx = find(app.settings.powerBands(:,1).*(app.settings.powerBands(:,2)) > 0);
            bands = app.settings.powerBands(bands_idx,:);
            nBands = size(bands, 1);
            
            
            % Make Waitbar:
            wb = waitbar(0, ['Loading data: '], 'windowstyle', 'modal');
            frames = java.awt.Frame.getFrames();
            frames(end).setAlwaysOnTop(1);
            nFiles = numel(app.filenames);
            
            for file_i = 1:nFiles
                
                % Load Data:
                filename =   [app.filenamesPaths{file_i} '\' app.filenames{file_i} ];
                try
                    waitbar((file_i-1)/nFiles, wb, ['File: ' num2str(file_i) '/' num2str(nFiles) ' - Loading data...'])
                catch
                end
                [channels, record] = Load_Mat_Seiz(filename, app.original_sampRate, app.down_sampRate);    
                
                
                % Get Electrodes Separation (groups)
                groups_idx = Get_Groups_Idx(channels); 
                
                                
                % Make Bipolar Referencing:
                if strcmp(reference_type, 'bipolar')
                    [record, channels, groups_idx] = BipolarReferencing(record, channels, groups_idx);
                end
                
                % Calculate Power bands:
                try
                    waitbar((file_i-1)/nFiles + 0.5/nFiles, wb, ['File: ' num2str(file_i) '/' num2str(nFiles) ' - Calculating Power Bands...'  ])
                catch
                end
                
                seizure_power = [];
                for b = 1:nBands
                    seizure_power(:,:,b) = SeizurePower(record, app.down_sampRate, app.settings.window_size, app.settings.window_step, bands(b,:));
                end

                
                % Plot Stuff:
                Results_Figure(app, record, seizure_power, channels, groups_idx, bands, app.filenames{file_i})
            end
            
            try
                close(wb)
            catch
            end
            
        end

        % Value changed function: SamplingRateHzEditField
        function SamplingRateHzEditFieldValueChanged(app, event)
            new_sampRate = app.SamplingRateHzEditField.Value;
            app.original_sampRate = new_sampRate;
            app.Downsample_DropDown.Items = string(num2str(app.original_sampRate./app.downsample_fractions));
            app.Downsample_DropDown.Value = app.Downsample_DropDown.Items(1);
            app.down_sampRate = new_sampRate ;
        end

        % Callback function
        function DownsampletoHzEditFieldValueChanged(app, event)
            value = app.DownsampletoHzEditField.Value;
            
            % Get largest powerband value:
            max_band = max(app.settings.powerBands(:));
            
            if value < max_band * 2
                warndlg(['The downsampled frequency needs to be bigger than 2x' num2str(max_band) ' Hz'])
                app.DownsampletoHzEditField.Value = app.original_sampRate;
            else
                app.down_sampRate = value;
            end
        end

        % Value changed function: Band1_1
        function Band1_1ValueChanged(app, event)
            value = app.Band1_1.Value;
            if value == 0
                app.settings.powerBands(1,2) = 0;
                app.Band1_2.Value = 0;
            end
            
            if value > app.down_sampRate/2
                warndlg(['Frequency Band must be lower than ' num2str(app.down_sampRate) '/2 Hz'] )
                app.Band1_1.Value = app.down_sampRate/2;
                app.settings.powerBands(1,1) = app.down_sampRate/2;
            else
                app.settings.powerBands(1,1) = value;
            end
            
        end

        % Value changed function: Band1_2
        function Band1_2ValueChanged(app, event)
            value = app.Band1_2.Value;
            if value == 0
                app.settings.powerBands(1,1) = 0;
                app.Band1_1.Value = 0;
            end
            
            if value > app.down_sampRate/2
                warndlg(['Frequency Band must be lower than ' num2str(app.down_sampRate) '/2 Hz'] )
                app.Band1_2.Value = app.down_sampRate/2;
                app.settings.powerBands(1,2) = app.down_sampRate/2;
            else
                app.settings.powerBands(1,2) = value;
            end
            
        end

        % Value changed function: Band2_1
        function Band2_1ValueChanged(app, event)
            value = app.Band2_1.Value;
            if value == 0
                app.settings.powerBands(2,2) = 0;
                app.Band2_2.Value = 0;
            end
            
            
            if value > app.down_sampRate/2
                warndlg(['Frequency Band must be lower than ' num2str(app.down_sampRate) '/2 Hz'] )
                app.Band2_1.Value = app.down_sampRate/2;
                app.settings.powerBands(2,1) = app.down_sampRate/2;
            else
                app.settings.powerBands(2,1) = value;
            end
            
            
        end

        % Value changed function: Band2_2
        function Band2_2ValueChanged(app, event)
            value = app.Band2_2.Value;
            if value == 0
                app.settings.powerBands(2,1) = 0;
                app.Band2_1.Value = 0;
            end
            
            if value > app.down_sampRate/2
                warndlg(['Frequency Band must be lower than ' num2str(app.down_sampRate) '/2 Hz'] )
                app.Band2_2.Value = app.down_sampRate/2;
                app.settings.powerBands(2,2) = app.down_sampRate/2;
            else
                app.settings.powerBands(2,2) = value;
            end
            
            
        end

        % Value changed function: Band3_1
        function Band3_1ValueChanged(app, event)
            value = app.Band3_1.Value;
            if value == 0
                app.settings.powerBands(3,2) = 0;
                app.Band3_2.Value = 0;
            end
            
            if value > app.down_sampRate/2
                warndlg(['Frequency Band must be lower than ' num2str(app.down_sampRate) '/2 Hz'] )
                app.Band3_1.Value = app.down_sampRate/2;
                app.settings.powerBands(3,1) = app.down_sampRate/2;
            else
                app.settings.powerBands(3,1) = value;
            end
            
        end

        % Value changed function: Band3_2
        function Band3_2ValueChanged(app, event)
            value = app.Band3_2.Value;
            if value == 0
                app.settings.powerBands(3,1) = 0;
                app.Band3_1.Value = 0;
            end
                        
            if value > app.down_sampRate/2
                warndlg(['Frequency Band must be lower than ' num2str(app.down_sampRate) '/2 Hz'] )
                app.Band3_2.Value = app.down_sampRate/2;
                app.settings.powerBands(3,2) = app.down_sampRate/2;
            else
                app.settings.powerBands(3,2) = value;
            end
            
        end

        % Value changed function: Band4_1
        function Band4_1ValueChanged(app, event)
            value = app.Band4_1.Value;
            if value == 0
                app.settings.powerBands(4,2) = 0;
                app.Band4_2.Value = 0;
            end
                        
            if value > app.down_sampRate/2
                warndlg(['Frequency Band must be lower than ' num2str(app.down_sampRate) '/2 Hz'] )
                app.Band4_1.Value = app.down_sampRate/2;
                app.settings.powerBands(4,1) = app.down_sampRate/2;
            else
                app.settings.powerBands(4,1) = value;
            end
            
            
        end

        % Value changed function: Band4_2
        function Band4_2ValueChanged(app, event)
            value = app.Band4_2.Value;
            if value == 0
                app.settings.powerBands(4,1) = 0;
                app.Band4_1.Value = 0;
            end
                        
            if value > app.down_sampRate/2
                warndlg(['Frequency Band must be lower than ' num2str(app.down_sampRate) '/2 Hz'] )
                app.Band4_2.Value = app.down_sampRate/2;
                app.settings.powerBands(4,2) = app.down_sampRate/2;
            else
                app.settings.powerBands(4,2) = value;
            end
            
        end

        % Value changed function: Band5_1
        function Band5_1ValueChanged(app, event)
            value = app.Band5_1.Value;
            if value == 0
                app.settings.powerBands(5,2) = 0;
                app.Band5_2.Value = 0;
            end
            
            if value > app.down_sampRate/2
                warndlg(['Frequency Band must be lower than ' num2str(app.down_sampRate) '/2 Hz'] )
                app.Band5_1.Value = app.down_sampRate/2;
                app.settings.powerBands(5,1) = app.down_sampRate/2;
            else
                app.settings.powerBands(5,1) = value;
            end
            
        end

        % Value changed function: Band5_2
        function Band5_2ValueChanged(app, event)
            value = app.Band5_2.Value;
            if value == 0
                app.settings.powerBands(5,1) = 0;
                app.Band5_1.Value = 0;
            end
            
            
            if value > app.down_sampRate/2
                warndlg(['Frequency Band must be lower than ' num2str(app.down_sampRate) '/2 Hz'] )
                app.Band5_2.Value = app.down_sampRate/2;
                app.settings.powerBands(5,2) = app.down_sampRate/2;
            else
                app.settings.powerBands(5,2) = value;
            end
            
        end

        % Value changed function: WindowSizeEditField
        function WindowSizeEditFieldValueChanged(app, event)
            value = app.WindowSizeEditField.Value;
            app.settings.window_size = value;
        end

        % Value changed function: StepEditField
        function StepEditFieldValueChanged(app, event)
            value = app.StepEditField.Value;
            if value <= app.settings.window_size
                app.settings.window_step = value;
                
                if value == app.settings.window_size
                    warndlg(["Window Size = Windw Step." ...
                        "You won't be able to calculate the Spectrogram because the windows do not overlap"])
                end
            else
                app.StepEditField.Value = app.settings.window_step;
            end
        end

        % Value changed function: Downsample_DropDown
        function Downsample_DropDownValueChanged(app, event)
            
            value = str2double(app.Downsample_DropDown.Value);
            
            % Get largest powerband value:
            max_band = max(app.settings.powerBands(:));
            
            if value < max_band * 2
                warndlg(['The downsampled frequency needs to be bigger than 2x' num2str(max_band) ' Hz'])
                app.Downsample_DropDown.Value = num2str(app.original_sampRate);
            else
                app.down_sampRate = value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectFilesButton = uibutton(app.UIFigure, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.StartAnalysisButton = uibutton(app.UIFigure, 'push');
            app.StartAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartAnalysisButtonPushed, true);
            app.StartAnalysisButton.Text = 'Start Analysis';
            app.FilestoAnalyseListBox = uilistbox(app.UIFigure);
            app.FilestoAnalyseListBox.Items = {};
            app.FilestoAnalyseListBox.ValueChangedFcn = createCallbackFcn(app, @FilestoAnalyseListBoxValueChanged, true);
            app.FilestoAnalyseListBox.Value = {};
            app.DeleteSelectedFileButton = uibutton(app.UIFigure, 'push');
            app.DeleteSelectedFileButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedFileButtonPushed, true);
            app.DeleteSelectedFileButton.Enable = 'off';
            app.DeleteSelectedFileButton.Text = 'Delete Selected File';
            app.SamplingRateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingRateHzEditFieldLabel.Text = 'Sampling Rate [Hz]';
            app.SamplingRateHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRateHzEditFieldValueChanged, true);
            app.WindowWidthsLabel = uilabel(app.UIFigure);
            app.WindowWidthsLabel.Text = 'Window Width [s]';
            app.WindowSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @WindowSizeEditFieldValueChanged, true);
            app.WindowStepsLabel = uilabel(app.UIFigure);
            app.WindowStepsLabel.Text = 'Window Step [s]';
            app.StepEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepEditField.ValueChangedFcn = createCallbackFcn(app, @StepEditFieldValueChanged, true);
            app.Band1_1 = uieditfield(app.UIFigure, 'numeric');
            app.Band1_1.ValueChangedFcn = createCallbackFcn(app, @Band1_1ValueChanged, true);
            app.Band1_2 = uieditfield(app.UIFigure, 'numeric');
            app.Band1_2.ValueChangedFcn = createCallbackFcn(app, @Band1_2ValueChanged, true);
            app.Band2_2 = uieditfield(app.UIFigure, 'numeric');
            app.Band2_2.ValueChangedFcn = createCallbackFcn(app, @Band2_2ValueChanged, true);
            app.Band2EditFieldLabel = uilabel(app.UIFigure);
            app.Band2EditFieldLabel.Text = 'Band 2';
            app.Band2_1 = uieditfield(app.UIFigure, 'numeric');
            app.Band2_1.ValueChangedFcn = createCallbackFcn(app, @Band2_1ValueChanged, true);
            app.Band3_2 = uieditfield(app.UIFigure, 'numeric');
            app.Band3_2.ValueChangedFcn = createCallbackFcn(app, @Band3_2ValueChanged, true);
            app.Band3EditField_2Label = uilabel(app.UIFigure);
            app.Band3EditField_2Label.Text = 'Band 3';
            app.Band3_1 = uieditfield(app.UIFigure, 'numeric');
            app.Band3_1.ValueChangedFcn = createCallbackFcn(app, @Band3_1ValueChanged, true);
            app.Band4_2 = uieditfield(app.UIFigure, 'numeric');
            app.Band4_2.ValueChangedFcn = createCallbackFcn(app, @Band4_2ValueChanged, true);
            app.Band4EditFieldLabel = uilabel(app.UIFigure);
            app.Band4EditFieldLabel.Text = 'Band 4';
            app.Band4_1 = uieditfield(app.UIFigure, 'numeric');
            app.Band4_1.ValueChangedFcn = createCallbackFcn(app, @Band4_1ValueChanged, true);
            app.Band5_2 = uieditfield(app.UIFigure, 'numeric');
            app.Band5_2.ValueChangedFcn = createCallbackFcn(app, @Band5_2ValueChanged, true);
            app.Band5EditFieldLabel = uilabel(app.UIFigure);
            app.Band5EditFieldLabel.Text = 'Band 5';
            app.Band5_1 = uieditfield(app.UIFigure, 'numeric');
            app.Band5_1.ValueChangedFcn = createCallbackFcn(app, @Band5_1ValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '-';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '-';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '-';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '-';
            app.DownsampleDataLabel = uilabel(app.UIFigure);
            app.DownsampleDataLabel.Text = 'Downsample Data';
            app.WindowingParametersLabel = uilabel(app.UIFigure);
            app.WindowingParametersLabel.Text = 'Windowing Parameters';
            app.PowerBandsHzLabel = uilabel(app.UIFigure);
            app.PowerBandsHzLabel.Text = 'Power Bands [Hz]';
            app.DomingosCastroPauloAguiarLabel = uilabel(app.UIFigure);
            app.DomingosCastroPauloAguiarLabel.Text = 'Domingos Castro, Paulo Aguiar';
            app.PowerMLabel = uilabel(app.UIFigure);
            app.PowerMLabel.Text = 'Power M';
            app.appLabel = uilabel(app.UIFigure);
            app.appLabel.Text = 'app';
            app.domingoscastroi3supptLabel = uilabel(app.UIFigure);
            app.domingoscastroi3supptLabel.Text = {'domingos.castro@i3s.up.pt'; ''};
            app.DownsampletoHzDropDownLabel = uilabel(app.UIFigure);
            app.DownsampletoHzDropDownLabel.Text = 'Downsample to [Hz]';
            app.Downsample_DropDown = uidropdown(app.UIFigure);
            app.Downsample_DropDown.Items = {};
            app.Downsample_DropDown.ValueChangedFcn = createCallbackFcn(app, @Downsample_DropDownValueChanged, true);
            app.Downsample_DropDown.Value = {};
            app.Band1EditFieldLabel_2 = uilabel(app.UIFigure);
            app.Band1EditFieldLabel_2.Text = 'Band 1';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.MonopolarButton = uiradiobutton(app.ButtonGroup);
            app.MonopolarButton.Text = 'Monopolar';
            app.MonopolarButton.Value = true;
            app.BipolarButton = uiradiobutton(app.ButtonGroup);
            app.BipolarButton.Text = 'Bipolar';
            app.ReferenceTypeLabel = uilabel(app.UIFigure);
            app.ReferenceTypeLabel.Text = 'Reference Type';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = power_mApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end